classdef GUIproyectoVision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        infoPage               matlab.ui.container.Tab
        processingPage         matlab.ui.container.Tab
        imagenBase             matlab.ui.control.Image
        DetectarobjetosButton  matlab.ui.control.Button
        CargaunaimagenButton   matlab.ui.control.Button
        ResultadoPanel         matlab.ui.container.Panel
        tituloCoches           matlab.ui.control.Label
        numPersParaCruzarText  matlab.ui.control.Label
        tituloPersonas         matlab.ui.control.Label
        numCochesText          matlab.ui.control.Label
    end

    
    properties (Access = private)
        imageParaProcesar % Description
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargaunaimagenButton
        function CargaunaimagenButtonPushed(app, event)
            [filename, pathname]= uigetfile({'*.jpg;*.tif;*.png;*.gif','All Image Files'; '*.*','All Files' },'Choose an image file ...');
            
            if isequal(filename,0)
                warndlg('File not selected');
                return
            end
            
            image=imread(strcat(pathname,filename));
            
            if not(size(image,3)==3)
                errordlg('The image must be colored');
                return
            end
            
            app.imagenBase.ImageSource = image;
        end

        % Button pushed function: DetectarobjetosButton
        function DetectarobjetosButtonPushed(app, event)
           imageSource = app.imagenBase.ImageSource;
           
           elementos = procesarImg(imageSource);
           
           app.numCochesText.Text = num2str(elementos(1));
           app.numPersParaCruzarText.Text = num2str(elementos(2));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.infoPage = uitab(app.TabGroup);
            app.infoPage.Title = 'Inicio';
            app.processingPage = uitab(app.TabGroup);
            app.processingPage.Title = 'Procesamiento';
            app.imagenBase = uiimage(app.processingPage);
            app.imagenBase.ImageSource = 'imagPasoCebra2.jpg';
            app.DetectarobjetosButton = uibutton(app.processingPage, 'push');
            app.DetectarobjetosButton.ButtonPushedFcn = createCallbackFcn(app, @DetectarobjetosButtonPushed, true);
            app.DetectarobjetosButton.Text = 'Detectar objetos';
            app.CargaunaimagenButton = uibutton(app.processingPage, 'push');
            app.CargaunaimagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargaunaimagenButtonPushed, true);
            app.CargaunaimagenButton.Text = 'Carga una imagen';
            app.ResultadoPanel = uipanel(app.processingPage);
            app.ResultadoPanel.Title = 'Resultado:';
            app.tituloCoches = uilabel(app.ResultadoPanel);
            app.tituloCoches.Text = 'Nº de Coches:';
            app.numPersParaCruzarText = uilabel(app.ResultadoPanel);
            app.numPersParaCruzarText.Text = '...';
            app.tituloPersonas = uilabel(app.ResultadoPanel);
            app.tituloPersonas.Text = 'Nº de personas para cruzar:';
            app.numCochesText = uilabel(app.ResultadoPanel);
            app.numCochesText.Text = '...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIproyectoVision

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end